classdef PDC2017a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PDCGUI                matlab.ui.Figure
        TabGroup              matlab.ui.container.TabGroup
        FasoresTab            matlab.ui.container.Tab
        FrecuenciaLabel       matlab.ui.control.Label
        ConexionPanel         matlab.ui.container.Panel
        btnConectar           matlab.ui.control.StateButton
        efIP                  matlab.ui.control.EditField
        efIPLabel             matlab.ui.control.Label
        efIDCODE              matlab.ui.control.NumericEditField
        IDCODEEditFieldLabel  matlab.ui.control.Label
        efPuerto              matlab.ui.control.NumericEditField
        PuertoEditFieldLabel  matlab.ui.control.Label
        DatosPanel            matlab.ui.container.Panel
        TiempoLabel           matlab.ui.control.Label
        tiempoLbl             matlab.ui.control.Label
        freqLbl               matlab.ui.control.Label
        PMUDataTbl            matlab.ui.control.Table
        angsAx                matlab.ui.control.UIAxes
        magAx                 matlab.ui.control.UIAxes
        phasorsAx             matlab.ui.control.UIAxes
        ConfigTab             matlab.ui.container.Tab
        txtaDataFrame         matlab.ui.control.TextArea
        txtaDataFrameLabel    matlab.ui.control.Label
        paramTbl              matlab.ui.control.Table
        PhasorsTbl            matlab.ui.control.Table
    end

    
    properties (Access = private)
    end
    
    properties (Access = public)
        stopReadings % Para detener la lectura de datos del PMU
        PHCfg % Configuracion de fasores leido del dataframe como nombre y tipo
        vecMags % vector de magnitudes para el plot
        vecDates % vector de fechas (ejex) para el plot
        vecAngs % vector de angulos para el plot
    end
    
    methods (Access = private)
        
        
        function  drawPhAxFcn(app,phasors)
            %Dibujar grafico de fasores
            xlim(app.phasorsAx,[-1.15,1.15]);
            ylim(app.phasorsAx,[-1.15,1.15]);
            set(app.phasorsAx,'Visible','off');
            ejex = -1:0.1:1;
            ejey= zeros(1,size(ejex,2));
            plot(app.phasorsAx,ejex,ejey,'k','LineWidth',1);
            hold(app.phasorsAx,'on');
            plot(app.phasorsAx,ejey,ejex,'k','LineWidth',1);
            
            % circulos
            x=0;
            y=0;
            th = 0:pi/50:2*pi;
            for r =.2:.2:1
                xunit = r * cos(th) + x;
                yunit = r * sin(th) + y;
                plot(app.phasorsAx,    xunit, yunit,'k:');
            end
            
            % lineas
            %L = largo
            %alpha= angulo
            for alpha=0:15:345
                x=0;
                y=0;
                L=1;
                L2 =1.13;%circulo de letras
                x2=x+(L*cosd(alpha));
                y2=y+(L*sind(alpha));
                xl=x+(L2*cosd(alpha));
                yl=y+(L2*sind(alpha));
                plot(app.phasorsAx,[x x2],[y y2],'k:');
                text(app.phasorsAx,xl-.11,yl,[num2str(alpha) +'°']);
            end
            
            %fasores
            for i=1:size(phasors,1)
                x=0;
                y=0;
                L=phasors(i,1);
                x2=x+(L*cosd(phasors(i,2)));
                y2=y+(L*sind(phasors(i,2)));
                plot(app.phasorsAx,[x x2],[y y2],'r','LineWidth',3 ,...
                    'Color', hsv2rgb([1.2-((i/10)*2),1,1]) );
            end
            hold(app.phasorsAx,'off') ;
            
        end
        
    end
    
    methods (Access = public)
        
        function  updateDataGUI(app,PMUData,tiempo,freq,cfgData)
            
            %actualiza los datos que se presentan en la GUI
            %cfgData: datos de configuracion del PMU (cfg frame)
            pdata = cell(cfgData.PH_NUMBER,4);%5 es el numero de columnas de la tabla
            
            for i = 1:cfgData.PH_NUMBER
                
                pdata(i,:) = [app.PHCfg{i,2} app.PHCfg(i,3)... etiqueta y tipo
                    [num2str(PMUData(i,1)/1000) 'kV'] PMUData(i,2)]; %magnitud y fase
            end
            
            %%%%%%%%%% dibujar los fasores ESTA PARTE SE PUEDE VECTORIZAR
            %para el color de las filas de la tabla
            coloresTbl = zeros(cfgData.PH_NUMBER,3); %numero de filasxrgb 3columnas
            coloresTbl(1,:)=[1,0,0];
            
            idxa = find(strcmp(pdata, 'VAPM            ')); %primer fasor de voltaje segun la documentacion
            
            if idxa > 0
                
                %primer fasor referenciado a cero
                %magFas = [str2double(pdata{idx,3}(:,1:end-2)), pdata{idx,4}]; %valor reportado por la PMU
                angPHAA = pdata{idxa,4}; %angulo de referencia, fase A
                magFas = [str2double(pdata{idxa,3}(:,1:end-2)), 0]; %valor reportado por la PMU
                coloresTbl(idxa,:)= hsv2rgb([1.2-((1/10)*2),1,1])    ;
                
            end
            
            idxb = find(strcmp(pdata, 'VBPM            ')); %segundo fasor de voltaje segun la documentacion
            
            if idxb > 0
                angDirPHAB =pdata{idxb,4};
                if angPHAA >= 0 && angDirPHAB <= 0
                    angRefPHAB =  angDirPHAB -angPHAA; %angulo referenciado a la fase b
                end
                if angPHAA <= 0 && angDirPHAB >= 0
                    angRefPHAB =  (angDirPHAB -angPHAA -360);
                end
                if angPHAA <= 0 && angDirPHAB <= 0
                    angRefPHAB =  angDirPHAB -angPHAA; %angulo referenciado a la fase b
                end
                if angPHAA >= 0 && angDirPHAB >= 0
                    angRefPHAB =  angDirPHAB -angPHAA; %angulo referenciado a la fase b
                end
                magFas(end+1,:) = [str2double(pdata{idxb,3}(:,1:end-2)), angRefPHAB];
                coloresTbl(idxb,:)= hsv2rgb([1.2-((2/10)*2),1,1])   ;
                
            end
            
            idxc = find(strcmp(pdata, 'VCPM            ')); %tercer fasor de voltaje segun la documentacion
            
            if idxc > 0
                angDirPHAC = pdata{idxc,4};
                if angPHAA >= 0 && angDirPHAC <= 0
                    angRefPHAC =  angDirPHAC- angPHAA +360; %angulo referenciado a la fase b
                end
                if angPHAA <= 0 && angDirPHAC >= 0
                    angRefPHAC =  angDirPHAC -angPHAA ;
                end
                if angPHAA <= 0 && angDirPHAC <= 0
                    angRefPHAC =  angDirPHAC -angPHAA; %angulo referenciado a la fase b
                end
                if angPHAA >= 0 && angDirPHAC >= 0
                    angRefPHAC =  angDirPHAC -angPHAA; %angulo referenciado a la fase b
                end
                magFas(end+1,:) = [str2double(pdata{idxc,3}(:,1:end-2)),angRefPHAC];
                coloresTbl(idxc,:)= hsv2rgb([1.2-((3/10)*2),1,1])    ;
                
            end
            
            %voltaje de secuencia positiva, solo tiene que restarse el angulo de referencia de la fase A (prueba visual)
            idxSP = find(strcmp(pdata, 'V1PM            ')); %tercer fasor de voltaje segun la documentacion
            if idxSP >0
                angRefSP = pdata{idxSP,4}-angPHAA;
            end
            
            %cambiando colores a fila de tablas
            app.PMUDataTbl.BackgroundColor  =coloresTbl;
            mgfnorm = [magFas(:,1)./max(magFas(:,1)) magFas(:,2)];
            drawPhAxFcn(app,mgfnorm)
            
            %%%%%% LLENAR LAS TABLAS%%%%%%
            pdataComp = [pdata pdata(1:end,4)]; %copiando la ultima fila correspondiente a los angulos
            
            %modificando los angulos que deben ser modificados de la ultima fila VA,VB,VC,V1PM (secuencia positiva)
            pdataComp{idxa,5}=0; %cero artificial, fase A como referencia
            pdataComp{idxb,5}=angRefPHAB; %angulo fase b
            pdataComp{idxc,5}=angRefPHAC; %angulo fase c
            pdataComp{idxSP,5} = angRefSP; %angulo referenciado de la secuencia positiva V1PM
            
            app.PMUDataTbl.Data = pdataComp;
            app.freqLbl.Text = num2str(cfgData.FREQ_NOM+freq);
            app.tiempoLbl.Text = char(tiempo);
            
            
            %%%%%%%%% dibujar en los plots %%%%%%%
            
            persistent k;
            
            if   isempty(k)
                
                k = 1;
                
            end
            
            if k <=100
                
                app.vecMags(:,k)= magFas(:,1);
                %app.vecAngs(:,k)= magFas(:,2);
                app.vecAngs(:,k)= [angPHAA angDirPHAB angDirPHAC]; %ANGULOS DIRECTOS DEL PMU
                app.vecDates(k)= tiempo;
                
            else
                
                app.vecMags=[app.vecMags(:,2:end) magFas(:,1)];
                app.vecAngs= [app.vecAngs(:,2:end) [angPHAA;angDirPHAB;angDirPHAC]]; %ANGULOS DIRECTOS DEL PMU
                app.vecDates= [app.vecDates(:,2:end) tiempo];
                cla(app.magAx);
                cla(app.angsAx);
                
            end
            
            hold(app.magAx,'on');
            plot(app.magAx,app.vecDates,app.vecMags(1,:),'color',hsv2rgb([1.2-((1/10)*2),1,1]),'LineWidth',2);
            plot(app.magAx,app.vecDates,app.vecMags(2,:),'color',hsv2rgb([1.2-((2/10)*2),1,1]),'LineWidth',2);
            plot(app.magAx,app.vecDates,app.vecMags(3,:),'color',hsv2rgb([1.2-((3/10)*2),1,1]),'LineWidth',2);
            hold(app.magAx,'off');
            
            hold(app.angsAx,'on');
            plot(app.angsAx,app.vecDates,app.vecAngs(1,:),'color',hsv2rgb([1.2-((1/10)*2),1,1]),'LineWidth',2);
            plot(app.angsAx,app.vecDates,app.vecAngs(2,:),'color',hsv2rgb([1.2-((2/10)*2),1,1]),'LineWidth',2);
            plot(app.angsAx,app.vecDates,app.vecAngs(3,:),'color',hsv2rgb([1.2-((3/10)*2),1,1]),'LineWidth',2);
            hold(app.angsAx,'off');
            
            
            k=k+1;
        end %funcion updatedataGUI
        
    end %methods
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.PMUDataTbl.ForegroundColor ='w';
            app.PMUDataTbl.FontWeight  = 'Bold';
            
            drawPhAxFcn(app,[0,0])%dibujo el grafico de los phasores
            
            %para el plot de los fasores
            app.vecDates=  NaT(1, 100);
            app.vecMags = zeros(3,100);
            app.vecAngs = zeros(3,100);
            set(app.magAx, 'Xdir', 'reverse');
            set(app.angsAx, 'Xdir', 'reverse');
            
        end

        % Value changed function: btnConectar
        function btnConectarValueChanged(app, event)
           
            value = app.btnConectar.Value;
            
            if value == true
           
                clc;
                cla(app.magAx);
                cla(app.angsAx);
           
                try
           
                    [tcpobj,dFrame] = conectarPMU(app.efIP.Value,app.efPuerto.Value,app.efIDCODE.Value);
           
                catch ME
           
                    msgbox(ME.identifier, 'Error','error');
                    app.btnConectar.Value=false;
                    return;
           
                end
                
                
                %%%%%%%%%%%%%%
                %PHCfg configuracion de los fasores como nombre y tipo
                if isempty(dFrame)
                    
                    msgbox('Se hizo la conexion pero ocurrio un error en la recepcion de datos','Error','error');
                    clear tcobj;
                    app.btnConectar.Value=false;
                    return;
                
                end
                
                app.btnConectar.Text='Desconectar';
                app.txtaDataFrame.Value = sprintf('%X',dFrame);
                [stCFG, app.PHCfg] =decodeCFG2(dFrame);
                stParam = stCFG;
                
                if stParam.FREQ_REP == 0
                
                    stParam.FREQ_REP = 'Fijo';
                
                else
                
                    stParam.FREQ_REP = 'Punto flotante';
                
                end
                
                if stParam.AN_REP ==0
                
                    stParam.AN_REP = 'Entero';
               
                else
                
                    stParam.AN_REP = 'Punto flotante';
               
                end
                
                if stParam.PH_REP == 0
                
                    stParam.PH_REP = 'Entero';
               
                else
                
                    stParam.PH_REP = 'Punto flotante';
                
                end
                
                if stParam.PH_POL_REP == 0
                
                    stParam.PH_POL_REP = 'Rectangular';
                
                else
                
                    stParam.PH_POL_REP = 'Polar';
               
                end
                
                if stParam.DATA_RATE > 0
                
                    stParam.DATA_RATE = sprintf('%d frames por segundo\n', stParam.DATA_RATE);
               
                else
                
                    stParam.DATA_RATE = sprinft('1 frame por cada %d segundos\n',abs( stParam.DATA_RATE));
                
                end
                
                stbl = [fieldnames(stCFG) struct2cell(stParam)];
                
                app.paramTbl.Data=stbl;
                app.PhasorsTbl.Data=app.PHCfg;
                
                %%%%%%%%%%%%%%%%%%%%%
                app.stopReadings = false;%bandera para iniciar o detener lecturas
                
                %instruccion para el envio de datos del PMU =2
                sendCMD(2,app.efIDCODE.Value ,tcpobj);
                %lectura del PMU [magnitud angulo]
                readDFPMU(tcpobj,stCFG,app.PHCfg, app,1); %cada dos segundos
                
                %%%%%%%%%%%%%%%%%%%%%%
            else
                app.btnConectar.Text='Conectar';
                app.stopReadings = true;
                clear tcpobj;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PDCGUI = uifigure('Visible', 'off');
            app.PDCGUI.Name = 'PDC';
            app.TabGroup = uitabgroup(app.PDCGUI);
            app.FasoresTab = uitab(app.TabGroup);
            app.FasoresTab.Title = 'Fasores';
            app.phasorsAx = uiaxes(app.FasoresTab);
            app.magAx = uiaxes(app.FasoresTab);
            ylabel(app.magAx, 'MAGNITUDE [kV]')
            app.angsAx = uiaxes(app.FasoresTab);
            ylabel(app.angsAx, 'PHASE [DEG]')
            app.PMUDataTbl = uitable(app.FasoresTab);
            app.PMUDataTbl.ColumnName = {'LABEL'; 'TYPE'; 'MAGNITUDE'; 'ANG REAL'; 'ANG REF'};
            app.PMUDataTbl.RowName = {};
            app.DatosPanel = uipanel(app.FasoresTab);
            app.DatosPanel.Title = 'Datos';
            app.freqLbl = uilabel(app.DatosPanel);
            app.freqLbl.Text = '';
            app.tiempoLbl = uilabel(app.DatosPanel);
            app.tiempoLbl.Text = '';
            app.TiempoLabel = uilabel(app.DatosPanel);
            app.TiempoLabel.Text = 'Tiempo:';
            app.ConexionPanel = uipanel(app.FasoresTab);
            app.ConexionPanel.Title = 'Conexion';
            app.PuertoEditFieldLabel = uilabel(app.ConexionPanel);
            app.PuertoEditFieldLabel.Text = 'Puerto';
            app.efPuerto = uieditfield(app.ConexionPanel, 'numeric');
            app.efPuerto.Value = 4712;
            app.IDCODEEditFieldLabel = uilabel(app.ConexionPanel);
            app.IDCODEEditFieldLabel.Text = 'IDCODE';
            app.efIDCODE = uieditfield(app.ConexionPanel, 'numeric');
            app.efIDCODE.Value = 1;
            app.efIPLabel = uilabel(app.ConexionPanel);
            app.efIPLabel.Text = 'IP';
            app.efIP = uieditfield(app.ConexionPanel, 'text');
            app.efIP.Value = '192.168.1.1';
            app.btnConectar = uibutton(app.ConexionPanel, 'state');
            app.btnConectar.ValueChangedFcn = createCallbackFcn(app, @btnConectarValueChanged, true);
            app.btnConectar.Text = 'Conectar';
            app.FrecuenciaLabel = uilabel(app.FasoresTab);
            app.FrecuenciaLabel.Text = 'Frecuencia:';
            app.ConfigTab = uitab(app.TabGroup);
            app.ConfigTab.Title = 'Config';
            app.PhasorsTbl = uitable(app.ConfigTab);
            app.PhasorsTbl.ColumnName = {'NAME'; 'LABEL'; 'TYPE'; 'FCONV'};
            app.PhasorsTbl.RowName = {};
            app.paramTbl = uitable(app.ConfigTab);
            app.paramTbl.ColumnName = {'Nombre'; 'Valor'};
            app.paramTbl.RowName = {};
            app.txtaDataFrameLabel = uilabel(app.ConfigTab);
            app.txtaDataFrameLabel.Text = 'DataFrame';
            app.txtaDataFrame = uitextarea(app.ConfigTab);
            % Show the figure after all components are created
            app.PDCGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PDC2017a

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PDCGUI)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PDCGUI)
        end
    end
end